classdef BackgroundFluorescence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        LoadDirButton                   matlab.ui.control.Button
        UITableICh2Tex                  matlab.ui.control.Table
        UITableICh1Tex                  matlab.ui.control.Table
        UITableICh2                     matlab.ui.control.Table
        UITableICh1                     matlab.ui.control.Table
        TexturedDecayRateEditField      matlab.ui.control.NumericEditField
        TexturedDecayRateEditFieldLabel  matlab.ui.control.Label
        AdjustTexturedBackgroundSwitch  matlab.ui.control.Switch
        AdjustTexturedBackgroundSwitchLabel  matlab.ui.control.Label
        TexturedIntensityChannel2EditField  matlab.ui.control.NumericEditField
        TexturedIntensityChannel2EditFieldLabel  matlab.ui.control.Label
        TexturedIntensityChannel1EditField  matlab.ui.control.NumericEditField
        TexturedIntensityChannel1EditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundFileEditField  matlab.ui.control.EditField
        TexturedBackgroundFileEditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundDirectoryEditField  matlab.ui.control.EditField
        TexturedBackgroundDirectoryEditFieldLabel  matlab.ui.control.Label
        TexturedBackgroundSwitch        matlab.ui.control.Switch
        TexturedBackgroundSwitchLabel   matlab.ui.control.Label
        DecayRateEditField              matlab.ui.control.NumericEditField
        DecayRateEditFieldLabel         matlab.ui.control.Label
        AdjustBackgroundSwitch          matlab.ui.control.Switch
        AdjustBackgroundSwitchLabel     matlab.ui.control.Label
        IntensityChannel2EditField      matlab.ui.control.NumericEditField
        IntensityChannel2EditFieldLabel  matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
        BackgroundFluorescenceLabel     matlab.ui.control.Label
        IntensityChannel1EditField      matlab.ui.control.NumericEditField
        IntensityChannel1EditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        BGpar=[]; % Initialize the parameters
        two_channel=[]; % Initialize two_channel
        laser_names=[]; % Initialize laser names
        TableLength=[]; % Initialize length of tables
        TextureDir=[]; % Initialize directory for texture files
        ImageSize=[]; % Initialize image size for texture files
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize EMCCDpar
            app.BGpar=mainapp.smis_gui_parameters.BG;
            % Initialize two_channel
            app.two_channel=mainapp.smis_gui_parameters.TwoChannel.state;
            % Initialize laser names
            lasers=mainapp.smis_gui_parameters.lasers;
            app.laser_names={lasers.name};
            % Initialize directory for texture files
            app.TextureDir=fullfile(mainapp.smis_gui_parameters.DefaultDirs.Pattern_dir,'2D');
            n_lasers=size(lasers,2);
            app.TableLength=min([100+100*n_lasers,app.UIFigure.Position(3)-100]); % Used to adjust the length of tables
            % Initialize image size for texture files
            image_size=mainapp.smis_gui_parameters.image_size;
            app.ImageSize=mainapp.smis_gui_parameters.binning.*[image_size.n,image_size.m];
            
            % Update UI with input values
            app.IntensityChannel1EditField.Value = app.BGpar.bg_ch1;
            app.IntensityChannel2EditField.Value = app.BGpar.bg_ch2;
            
            app.AdjustBackgroundSwitch.Value = on2one(app.BGpar.adjust_bg,'Off','On');
            app.UITableICh1.ColumnName=app.laser_names;
            app.UITableICh1.Data = zeros(1,n_lasers);
            app.UITableICh1.Data(1,:) = app.BGpar.bg_laser_sensitivity_ch1;
            app.UITableICh2.ColumnName=app.laser_names;
            app.UITableICh2.Data = zeros(1,n_lasers);
            app.UITableICh2.Data(1,:) = app.BGpar.bg_laser_sensitivity_ch2;
            app.DecayRateEditField.Value = app.BGpar.bg_decay_rate;
            
            app.TexturedBackgroundSwitch.Value = on2one(app.BGpar.add_textured_bg,'Off','On');
            app.TexturedBackgroundDirectoryEditField.Value = app.BGpar.textured_bg_pattern_dir;
            app.TexturedBackgroundFileEditField.Value = app.BGpar.textured_bg_pattern_file;
            app.TexturedIntensityChannel1EditField.Value = app.BGpar.textured_bg_ch1;
            app.TexturedIntensityChannel2EditField.Value = app.BGpar.textured_bg_ch2;
            
            app.AdjustTexturedBackgroundSwitch.Value = on2one(app.BGpar.adjust_textured_bg,'Off','On');
            app.UITableICh1Tex.ColumnName=app.laser_names;
            app.UITableICh1Tex.Data = zeros(1,n_lasers);
            app.UITableICh1Tex.Data(1,:) = app.BGpar.textured_bg_laser_sensitivity_ch1;
            app.UITableICh2Tex.ColumnName=app.laser_names;
            app.UITableICh2Tex.Data = zeros(1,n_lasers);
            app.UITableICh2Tex.Data(1,:) = app.BGpar.textured_bg_laser_sensitivity_ch2;
            app.TexturedDecayRateEditField.Value = app.BGpar.textured_bg_decay_rate;
            
            % Make some fields visible
            if app.two_channel==1
                app.IntensityChannel2EditField.Visible = 'on';
                app.IntensityChannel2EditFieldLabel.Visible = 'on';
            else
                app.IntensityChannel2EditField.Visible = 'off';
                app.IntensityChannel2EditFieldLabel.Visible = 'off';
            end
            
            if app.BGpar.adjust_bg==1
                app.UITableICh1.Visible = 'on';
                
                % Setup Table Position size
                app.UITableICh1.Position=[27,513,app.TableLength,51]; % Adjust position
                app.DecayRateEditField.Visible = 'on';
                app.DecayRateEditFieldLabel.Visible = 'on';
                if app.two_channel==1
                    app.UITableICh2.Position=[27,456,app.TableLength,51]; % Adjust position
                    app.UITableICh2.Visible = 'on';
                else
                    app.UITableICh2.Visible = 'off';
                end
            else
                app.UITableICh1.Visible = 'off';
                app.UITableICh2.Visible = 'off';
                app.DecayRateEditField.Visible = 'off';
                app.DecayRateEditFieldLabel.Visible = 'off';
            end
            
            if app.BGpar.add_textured_bg==1
                app.LoadDirButton.Visible='on';
                app.LoadFileButton.Visible='on';
                app.TexturedBackgroundDirectoryEditField.Visible = 'on';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'on';
                app.TexturedBackgroundFileEditField.Visible = 'on';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'on';
                app.AdjustTexturedBackgroundSwitch.Visible='on';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='on';
                app.TexturedIntensityChannel1EditField.Visible='on';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='on';
                if app.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='on';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='on';
                else
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                end
                
                if app.BGpar.adjust_textured_bg==1
                    app.UITableICh1Tex.Position=[27,178,app.TableLength,51]; % Adjust position
                    app.UITableICh1Tex.Visible = 'on';
                    app.TexturedDecayRateEditField.Visible = 'on';
                    app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                    if app.two_channel==1
                        app.UITableICh2Tex.Position=[27,121,app.TableLength,51]; % Adjust position
                        app.UITableICh2Tex.Visible = 'on';
                    end
                end
                
            else
                app.LoadDirButton.Visible='off';
                app.LoadFileButton.Visible='off';
                app.TexturedBackgroundDirectoryEditField.Visible = 'off';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'off';
                app.TexturedBackgroundFileEditField.Visible = 'off';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'off';
                app.AdjustTexturedBackgroundSwitch.Visible='off';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='off';
                app.TexturedIntensityChannel1EditField.Visible='off';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='off';
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                app.TexturedIntensityChannel2EditField.Visible='off';
                app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                app.UITableICh2Tex.Visible = 'off';
            end
            
            if app.BGpar.adjust_textured_bg==1 && app.BGpar.add_textured_bg==1
                app.UITableICh1Tex.Visible = 'on';
                app.TexturedDecayRateEditField.Visible = 'on';
                app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                if app.two_channel==1
                    app.UITableICh2Tex.Visible = 'on';
                else
                    app.UITableICh2Tex.Visible = 'off';
                end
            else
                app.UITableICh1Tex.Visible = 'off';
                app.UITableICh2Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Check that the fractional laser dependences at up to 1
            is_OK=1;
            %             if app.BGpar.adjust_bg==1
            %                 if abs(sum(app.BGpar.bg_laser_sensitivity_ch1)-1)<0.05 % Tolerate a 5% error and adjust
            %                     app.BGpar.bg_laser_sensitivity_ch1=app.BGpar.bg_laser_sensitivity_ch1/sum(app.BGpar.bg_laser_sensitivity_ch1);
            %                 else
            %                     warndlg('Sum of fractional laser sensitivities in channel 1 must add up to 1 !','Warning');
            %                     is_OK=0;
            %                 end
            %                 if app.two_channel==1
            %                     if abs(sum(app.BGpar.bg_laser_sensitivity_ch2)-1)<0.05 % Tolerate a 5% error and adjust
            %                         app.BGpar.bg_laser_sensitivity_ch2=app.BGpar.bg_laser_sensitivity_ch2/sum(app.BGpar.bg_laser_sensitivity_ch2);
            %                     else
            %                         warndlg('Sum of fractional laser sensitivities in channel 2 must add up to 1 !','Warning');
            %                         is_OK=0;
            %                     end
            %                 end
            %             end
            
            if app.BGpar.add_textured_bg==1
                MyFile = app.TexturedBackgroundFileEditField.Value;
                MyDir = app.BGpar.textured_bg_pattern_dir;
                % Check that the file exists
                if exist(fullfile(MyDir,MyFile),'file')~=2
                    MyDlg=warndlg('A texture image file has not been loaded !','Warning');
                    waitfor(MyDlg)
                    is_OK=0;
                end
            end
            
            %             if app.BGpar.adjust_textured_bg==1
            %                 if abs(sum(app.BGpar.textured_bg_laser_sensitivity_ch1)-1)<0.05 % Tolerate a 5% error and adjust
            %                     app.BGpar.textured_bg_laser_sensitivity_ch1=app.BGpar.textured_bg_laser_sensitivity_ch1/sum(app.BGpar.textured_bg_laser_sensitivity_ch1);
            %                 else
            %                     warndlg('Sum of textured fractional laser sensitivities in channel 1 must add up to 1 !','Warning');
            %                     is_OK=0;
            %                 end
            %                 if app.two_channel==1
            %                     if abs(sum(app.BGpar.textured_bg_laser_sensitivity_ch2)-1)<0.05 % Tolerate a 5% error and adjust
            %                         app.BGpar.textured_bg_laser_sensitivity_ch2=app.BGpar.textured_bg_laser_sensitivity_ch2/sum(app.BGpar.textured_bg_laser_sensitivity_ch2);
            %                     else
            %                         warndlg('Sum of textured fractional laser sensitivities in channel 2 must add up to 1 !','Warning');
            %                         is_OK=0;
            %                     end
            %                 end
            %             end
            
            if is_OK==1
                % Call main app's public function
                updateBG(app.CallingApp, app.BGpar)
                % Delete the dialog box
                delete(app)
            end
        end

        % Value changed function: IntensityChannel1EditField
        function IntensityChannel1EditFieldValueChanged(app, event)
            value = app.IntensityChannel1EditField.Value;
            app.BGpar.bg_ch1=value;
        end

        % Value changed function: IntensityChannel2EditField
        function IntensityChannel2EditFieldValueChanged(app, event)
            value = app.IntensityChannel2EditField.Value;
            app.BGpar.bg_ch2=value;
        end

        % Callback function
        function LaserSensitivityChannel1EditFieldValueChanged(app, event)
            value = app.LaserSensitivityChannel1EditField.Value;
            app.BGpar.bg_laser_sensitivity_ch1=value;
        end

        % Callback function
        function LaserSensitivityChannel2EditFieldValueChanged(app, event)
            value = app.LaserSensitivityChannel2EditField.Value;
            app.BGpar.bg_laser_sensitivity_ch2=value;
        end

        % Value changed function: DecayRateEditField
        function DecayRateEditFieldValueChanged(app, event)
            value = app.DecayRateEditField.Value;
            app.BGpar.bg_decay_rate=value;
        end

        % Value changed function: TexturedBackgroundSwitch
        function TexturedBackgroundSwitchValueChanged(app, event)
            value = app.TexturedBackgroundSwitch.Value;
            app.BGpar.add_textured_bg=on2one(value,'Off','On');
            
            if strcmp(value,'On')
                app.LoadDirButton.Visible='on';
                app.LoadFileButton.Visible='on';
                app.TexturedBackgroundDirectoryEditField.Visible = 'on';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'on';
                app.TexturedBackgroundFileEditField.Visible = 'on';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'on';
                app.AdjustTexturedBackgroundSwitch.Visible='on';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='on';
                app.TexturedIntensityChannel1EditField.Visible='on';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='on';
                if app.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='on';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='on';
                else
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                end
                
                if strcmp(app.AdjustTexturedBackgroundSwitch.Value,'On')
                    app.UITableICh1Tex.Visible = 'on';
                    app.TexturedDecayRateEditField.Visible = 'on';
                    app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                    
                    if app.two_channel==1
                        app.UITableICh2Tex.Visible = 'on';
                    end
                end
                
            else
                app.LoadDirButton.Visible='off';
                app.LoadFileButton.Visible='off';
                app.TexturedBackgroundDirectoryEditField.Visible = 'off';
                app.TexturedBackgroundDirectoryEditFieldLabel.Visible = 'off';
                app.TexturedBackgroundFileEditField.Visible = 'off';
                app.TexturedBackgroundFileEditFieldLabel.Visible = 'off';
                app.AdjustTexturedBackgroundSwitch.Visible='off';
                app.AdjustTexturedBackgroundSwitchLabel.Visible='off';
                app.TexturedIntensityChannel1EditField.Visible='off';
                app.TexturedIntensityChannel1EditFieldLabel.Visible='off';
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                if app.two_channel==1
                    app.TexturedIntensityChannel2EditField.Visible='off';
                    app.TexturedIntensityChannel2EditFieldLabel.Visible='off';
                    app.UITableICh2Tex.Visible = 'off';
                end
                
            end
            
        end

        % Value changed function: AdjustBackgroundSwitch
        function AdjustBackgroundSwitchValueChanged(app, event)
            value = app.AdjustBackgroundSwitch.Value;
            app.BGpar.adjust_bg=on2one(value,'Off','On');
            if strcmp(value,'On')
                app.UITableICh1.Position=[27,513,app.TableLength,51]; % Adjust position
                app.UITableICh1.Visible = 'on';
                app.DecayRateEditField.Visible = 'on';
                app.DecayRateEditFieldLabel.Visible = 'on';
                if app.two_channel==1
                    app.UITableICh2.Position=[27,456,app.TableLength,51]; % Adjust position
                    app.UITableICh2.Visible = 'on';
                end
            else
                app.UITableICh1.Visible = 'off';
                app.DecayRateEditField.Visible = 'off';
                app.DecayRateEditFieldLabel.Visible = 'off';
                if app.two_channel==1
                    app.UITableICh2.Visible = 'off';
                end
            end
        end

        % Value changed function: TexturedBackgroundDirectoryEditField
        function TexturedBackgroundDirectoryEditFieldValueChanged(app, event)
            value = app.TexturedBackgroundDirectoryEditField.Value;
            app.BGpar.textured_bg_pattern_dir=value;
        end

        % Value changed function: TexturedBackgroundFileEditField
        function TexturedBackgroundFileEditFieldValueChanged(app, event)
            MyFile = app.TexturedBackgroundFileEditField.Value;
            MyDir = app.BGpar.textured_bg_pattern_dir;
            % Check that the file exists
            if exist(fullfile(MyDir,MyFile),'file')==2
                % Check that image size is compatible
                tx_pattern=imread(fullfile(MyDir,MyFile));
                if ~isempty(app.ImageSize)
                    if size(tx_pattern,1)==app.ImageSize(1) && size(tx_pattern,2)==app.ImageSize(2)
                        app.TexturedBackgroundFileEditField.Value=MyFile;
                        app.BGpar.textured_bg_pattern_file=MyFile;
                    else
                        MyDlg=warndlg('Size of textured background image should be the same size as main image size in X and Y dimensions !','Warning');
                        waitfor(MyDlg)
                    end
                else
                    MyDlg=warndlg('A virtual sample must be loaded first !','Warning');
                    waitfor(MyDlg)
                end
            else
                MyDlg=warndlg('File does not exist !','Warning');
                waitfor(MyDlg)
            end
            
            
        end

        % Value changed function: TexturedIntensityChannel1EditField
        function TexturedIntensityChannel1EditFieldValueChanged(app, event)
            value = app.TexturedIntensityChannel1EditField.Value;
            app.BGpar.textured_bg_ch1=value;
        end

        % Value changed function: TexturedIntensityChannel2EditField
        function TexturedIntensityChannel2EditFieldValueChanged(app, event)
            value = app.TexturedIntensityChannel2EditField.Value;
            app.BGpar.textured_bg_ch2=value;
        end

        % Value changed function: AdjustTexturedBackgroundSwitch
        function AdjustTexturedBackgroundSwitchValueChanged(app, event)
            value = app.AdjustTexturedBackgroundSwitch.Value;
            app.BGpar.adjust_textured_bg=on2one(value,'Off','On');
            
            if strcmp(value,'On')
                app.UITableICh1Tex.Position=[27,178,app.TableLength,51]; % Adjust position
                app.UITableICh1Tex.Visible = 'on';
                app.TexturedDecayRateEditField.Visible = 'on';
                app.TexturedDecayRateEditFieldLabel.Visible = 'on';
                
                if app.two_channel==1
                    app.UITableICh2Tex.Position=[27,121,app.TableLength,51]; % Adjust position
                    app.UITableICh2Tex.Visible = 'on';
                end
            else
                app.UITableICh1Tex.Visible = 'off';
                app.TexturedDecayRateEditField.Visible = 'off';
                app.TexturedDecayRateEditFieldLabel.Visible = 'off';
                
                if app.two_channel==1
                    app.UITableICh2Tex.Visible = 'off';
                end
            end
            
        end

        % Value changed function: TexturedDecayRateEditField
        function TexturedDecayRateEditFieldValueChanged(app, event)
            value = app.TexturedDecayRateEditField.Value;
            app.BGpar.textured_bg_decay_rate=value;
        end

        % Display data changed function: UITableICh1
        function UITableICh1DisplayDataChanged(app, event)
            newDisplayData = app.UITableICh1.DisplayData;
            app.BGpar.bg_laser_sensitivity_ch1=newDisplayData;
        end

        % Display data changed function: UITableICh2
        function UITableICh2DisplayDataChanged(app, event)
            newDisplayData = app.UITableICh2.DisplayData;
            app.BGpar.bg_laser_sensitivity_ch2=newDisplayData;
        end

        % Display data changed function: UITableICh1Tex
        function UITableICh1TexDisplayDataChanged(app, event)
            newDisplayData = app.UITableICh1Tex.DisplayData;
            app.BGpar.textured_bg_laser_sensitivity_ch1=newDisplayData;
        end

        % Display data changed function: UITableICh2Tex
        function UITableICh2TexDisplayDataChanged(app, event)
            newDisplayData = app.UITableICh2Tex.DisplayData;
            app.BGpar.textured_bg_laser_sensitivity_ch2=newDisplayData;
        end

        % Button pushed function: LoadDirButton
        function LoadDirButtonPushed(app, event)
            MyDir = uigetdir(app.TextureDir,'Select a directory where texture pattern files are stored');
            if isequal(MyDir,0)
                disp('User selected Cancel');
            else
                app.TextureDir=MyDir;
                app.TexturedBackgroundDirectoryEditField.Value=MyDir;
                app.BGpar.textured_bg_pattern_dir=MyDir;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            cd(app.TextureDir);
            [MyFile,MyDir] = uigetfile('*.tif','Select a pattern file');
            if ~isequal(MyFile,0)
                % Check that image size is compatible
                tx_pattern=imread(fullfile(MyDir,MyFile));
                if ~isempty(app.ImageSize)
                    if size(tx_pattern,1)==app.ImageSize(1) && size(tx_pattern,2)==app.ImageSize(2)
                        app.TexturedBackgroundFileEditField.Value=MyFile;
                        app.TexturedBackgroundDirectoryEditField.Value=MyDir;
                        app.BGpar.textured_bg_pattern_file=MyFile;
                        app.BGpar.textured_bg_pattern_dir=MyDir;
                    else
                        MyDlg=warndlg('Size of textured background image should be the same size as main image size in X and Y dimensions !','Warning');
                        waitfor(MyDlg)
                    end
                else
                    MyDlg=warndlg('A virtual sample must be loaded first !','Warning');
                    waitfor(MyDlg)
                end
            else
                disp('User selected Cancel');
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IntensityChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.IntensityChannel1EditFieldLabel.Text = 'Intensity Channel 1';
            app.IntensityChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityChannel1EditField.Limits = [0 Inf];
            app.IntensityChannel1EditField.ValueDisplayFormat = '%11.0d';
            app.IntensityChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityChannel1EditFieldValueChanged, true);
            app.IntensityChannel1EditField.Tooltip = {'Background Intensity in Channel 1 [Ph/100x100nm²/100W/cm²/s]'};
            app.IntensityChannel1EditField.Value = 1;
            app.BackgroundFluorescenceLabel = uilabel(app.UIFigure);
            app.BackgroundFluorescenceLabel.Text = 'Background Fluorescence';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.IntensityChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.IntensityChannel2EditFieldLabel.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/Frame]'};
            app.IntensityChannel2EditFieldLabel.Text = 'Intensity Channel 2 ';
            app.IntensityChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityChannel2EditField.Limits = [0 Inf];
            app.IntensityChannel2EditField.ValueDisplayFormat = '%11.0d';
            app.IntensityChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @IntensityChannel2EditFieldValueChanged, true);
            app.IntensityChannel2EditField.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/100W/cm²/s]'};
            app.IntensityChannel2EditField.Value = 1;
            app.AdjustBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.AdjustBackgroundSwitchLabel.Text = 'Adjust Background';
            app.AdjustBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdjustBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @AdjustBackgroundSwitchValueChanged, true);
            app.AdjustBackgroundSwitch.Tooltip = {'Adjust background as a function of local laser power density'};
            app.DecayRateEditFieldLabel = uilabel(app.UIFigure);
            app.DecayRateEditFieldLabel.Text = 'Decay Rate ';
            app.DecayRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayRateEditField.Limits = [0 Inf];
            app.DecayRateEditField.ValueChangedFcn = createCallbackFcn(app, @DecayRateEditFieldValueChanged, true);
            app.DecayRateEditField.Tooltip = {'Background decay rate [uJ/100x100nm²/s]'};
            app.DecayRateEditField.Value = 1;
            app.TexturedBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundSwitchLabel.Text = 'Textured Background';
            app.TexturedBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.TexturedBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundSwitchValueChanged, true);
            app.TexturedBackgroundSwitch.Tooltip = {'Use this to force background fluorescence to follow a specific texture defined by an image pattern'};
            app.TexturedBackgroundDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundDirectoryEditFieldLabel.Text = 'Textured Background Directory';
            app.TexturedBackgroundDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.TexturedBackgroundDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundDirectoryEditFieldValueChanged, true);
            app.TexturedBackgroundFileEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedBackgroundFileEditFieldLabel.Text = 'Textured Background File';
            app.TexturedBackgroundFileEditField = uieditfield(app.UIFigure, 'text');
            app.TexturedBackgroundFileEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedBackgroundFileEditFieldValueChanged, true);
            app.TexturedIntensityChannel1EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedIntensityChannel1EditFieldLabel.Text = 'Textured Intensity Channel 1';
            app.TexturedIntensityChannel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedIntensityChannel1EditField.Limits = [0 Inf];
            app.TexturedIntensityChannel1EditField.ValueDisplayFormat = '%11.0d';
            app.TexturedIntensityChannel1EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedIntensityChannel1EditFieldValueChanged, true);
            app.TexturedIntensityChannel1EditField.Tooltip = {'Background Intensity in Channel 1 [Ph/100x100nm²/100W/cm²/s]'};
            app.TexturedIntensityChannel1EditField.Value = 1;
            app.TexturedIntensityChannel2EditFieldLabel = uilabel(app.UIFigure);
            app.TexturedIntensityChannel2EditFieldLabel.Text = 'Textured Intensity Channel 2';
            app.TexturedIntensityChannel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedIntensityChannel2EditField.Limits = [0 Inf];
            app.TexturedIntensityChannel2EditField.ValueDisplayFormat = '%11.0d';
            app.TexturedIntensityChannel2EditField.ValueChangedFcn = createCallbackFcn(app, @TexturedIntensityChannel2EditFieldValueChanged, true);
            app.TexturedIntensityChannel2EditField.Tooltip = {'Background Intensity in Channel 2 [Ph/100x100nm²/100W/cm²/s]'};
            app.TexturedIntensityChannel2EditField.Value = 1;
            app.AdjustTexturedBackgroundSwitchLabel = uilabel(app.UIFigure);
            app.AdjustTexturedBackgroundSwitchLabel.Text = 'Adjust Textured Background';
            app.AdjustTexturedBackgroundSwitch = uiswitch(app.UIFigure, 'slider');
            app.AdjustTexturedBackgroundSwitch.ValueChangedFcn = createCallbackFcn(app, @AdjustTexturedBackgroundSwitchValueChanged, true);
            app.AdjustTexturedBackgroundSwitch.Tooltip = {'Adjust textured background as a function of local laser power density'};
            app.TexturedDecayRateEditFieldLabel = uilabel(app.UIFigure);
            app.TexturedDecayRateEditFieldLabel.Text = 'Textured Decay Rate ';
            app.TexturedDecayRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.TexturedDecayRateEditField.Limits = [0 Inf];
            app.TexturedDecayRateEditField.ValueChangedFcn = createCallbackFcn(app, @TexturedDecayRateEditFieldValueChanged, true);
            app.TexturedDecayRateEditField.Tooltip = {'Textured background decay rate [uJ/100x100nm²/s]'};
            app.TexturedDecayRateEditField.Value = 1;
            app.UITableICh1 = uitable(app.UIFigure);
            app.UITableICh1.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh1.RowName = {'Intensity Ch1'};
            app.UITableICh1.ColumnEditable = true;
            app.UITableICh1.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh1DisplayDataChanged, true);
            app.UITableICh1.Tooltip = {'Enter fractional dependencies of background sensitivity on the various lasers in channel 1. '};
            app.UITableICh2 = uitable(app.UIFigure);
            app.UITableICh2.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh2.RowName = {'Intensity Ch2'};
            app.UITableICh2.ColumnEditable = true;
            app.UITableICh2.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh2DisplayDataChanged, true);
            app.UITableICh2.Tooltip = {'Enter fractional dependencies of background sensitivity on the various lasers in channel 2. '};
            app.UITableICh1Tex = uitable(app.UIFigure);
            app.UITableICh1Tex.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh1Tex.RowName = {'Intensity Ch1'};
            app.UITableICh1Tex.ColumnEditable = true;
            app.UITableICh1Tex.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh1TexDisplayDataChanged, true);
            app.UITableICh1Tex.Tooltip = {'Enter fractional dependencies of  textured background sensitivity on the various lasers in channel 1.'};
            app.UITableICh2Tex = uitable(app.UIFigure);
            app.UITableICh2Tex.ColumnName = {'Laser 1'; 'Laser 2'};
            app.UITableICh2Tex.RowName = {'Intensity Ch2'};
            app.UITableICh2Tex.ColumnEditable = true;
            app.UITableICh2Tex.DisplayDataChangedFcn = createCallbackFcn(app, @UITableICh2TexDisplayDataChanged, true);
            app.UITableICh2Tex.Tooltip = {'Enter fractional dependencies of  textured background sensitivity on the various lasers in channel 2. '};
            app.LoadDirButton = uibutton(app.UIFigure, 'push');
            app.LoadDirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDirButtonPushed, true);
            app.LoadDirButton.Text = 'Load';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackgroundFluorescence(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end