classdef Drift < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        NoisefractionalEditField       matlab.ui.control.NumericEditField
        NoisefractionalEditFieldLabel_4  matlab.ui.control.Label
        stOrdernmEditField             matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel_3      matlab.ui.control.Label
        ConstantnmEditField            matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel_3     matlab.ui.control.Label
        ndOrdernmEditField             matlab.ui.control.NumericEditField
        ndOrdernmEditFieldLabel        matlab.ui.control.Label
        ZTranslationalDriftLabel       matlab.ui.control.Label
        RotationNoiseEditField         matlab.ui.control.NumericEditField
        NoisefractionalEditFieldLabel_3  matlab.ui.control.Label
        RotationEditField              matlab.ui.control.NumericEditField
        RotationperframeLabel          matlab.ui.control.Label
        YRotationCenterEditField       matlab.ui.control.NumericEditField
        YCenterofRotationRasterLabel   matlab.ui.control.Label
        XRotationCenterEditField       matlab.ui.control.NumericEditField
        XCenterofRotationRasterLabel   matlab.ui.control.Label
        RotationalDriftLabel           matlab.ui.control.Label
        YNoisefractionalEditField      matlab.ui.control.NumericEditField
        NoisefractionalEditFieldLabel_2  matlab.ui.control.Label
        Y2ndOrdernmEditField           matlab.ui.control.NumericEditField
        stOrdernmEditField_2Label_2    matlab.ui.control.Label
        Y1stOrdernmEditField           matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel_2      matlab.ui.control.Label
        YConstantnmEditField           matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel_2     matlab.ui.control.Label
        YTranslationalDriftLabel       matlab.ui.control.Label
        XTranslationalDriftLabel       matlab.ui.control.Label
        XNoisefractionalEditField      matlab.ui.control.NumericEditField
        NoisefractionalEditFieldLabel  matlab.ui.control.Label
        X2ndOrdernmEditField           matlab.ui.control.NumericEditField
        stOrdernmEditField_2Label      matlab.ui.control.Label
        X1stOrdernmEditField           matlab.ui.control.NumericEditField
        stOrdernmEditFieldLabel        matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        DriftSetupLabel                matlab.ui.control.Label
        XConstantnmEditField           matlab.ui.control.NumericEditField
        ConstantnmEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Driftpar=[]; % Initialize the parameters
        Simul3D=[];
        ImageSize=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Initialize Driftpar
            app.Driftpar=mainapp.smis_gui_parameters.drift;
            % Initialize 3D option
            app.Simul3D=mainapp.smis_gui_parameters.simul_3D;
            % Initialize Image size
            app.ImageSize=mainapp.smis_gui_parameters.image_size;
            
            % Update UI with input values
            app.XConstantnmEditField.Value = app.Driftpar.x_drift(1);
            app.X1stOrdernmEditField.Value = app.Driftpar.x_drift(2);
            app.X2ndOrdernmEditField.Value = app.Driftpar.x_drift(3);
            app.XNoisefractionalEditField.Value = app.Driftpar.x_drift(4);
            
            app.YConstantnmEditField.Value = app.Driftpar.y_drift(1);
            app.Y1stOrdernmEditField.Value = app.Driftpar.y_drift(2);
            app.Y2ndOrdernmEditField.Value = app.Driftpar.y_drift(3);
            app.YNoisefractionalEditField.Value = app.Driftpar.y_drift(4);
            
            app.XRotationCenterEditField.Value = app.Driftpar.rot_drift(1);
            app.YRotationCenterEditField.Value = app.Driftpar.rot_drift(2);
            app.RotationEditField.Value = app.Driftpar.rot_drift(3);
            app.RotationNoiseEditField.Value = app.Driftpar.rot_drift(4);
            
            % Handles 3D option
            if app.Simul3D==1
                app.ZTranslationalDriftLabel.Visible='on';
                app.ConstantnmEditField.Visible='on';
                app.ConstantnmEditFieldLabel_3.Visible='on';
                app.stOrdernmEditField.Visible='on';
                app.stOrdernmEditFieldLabel_3.Visible='on';
                app.ndOrdernmEditField.Visible='on';
                app.ndOrdernmEditFieldLabel.Visible='on';
                app.NoisefractionalEditField.Visible='on';
                app.NoisefractionalEditFieldLabel_4.Visible='on';
                app.ConstantnmEditField.Value = app.Driftpar.z_drift(1);
                app.stOrdernmEditField.Value = app.Driftpar.z_drift(2);
                app.ndOrdernmEditField.Value = app.Driftpar.z_drift(3);
                app.NoisefractionalEditField.Value = app.Driftpar.z_drift(4);

            else
                app.ZTranslationalDriftLabel.Visible='off';
                app.stOrdernmEditField.Visible='off';
                app.stOrdernmEditFieldLabel_3.Visible='off';
                app.ndOrdernmEditField.Visible='off';
                app.ndOrdernmEditFieldLabel.Visible='off';
                app.ConstantnmEditField.Visible='off';
                app.ConstantnmEditFieldLabel_3.Visible='off';
                app.NoisefractionalEditField.Visible='off';
                app.NoisefractionalEditFieldLabel_4.Visible='off';
                
            end
            
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Call main app's public function
            updateDrift(app.CallingApp, app.Driftpar)
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: XConstantnmEditField
        function XConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.x_drift(1) = app.XConstantnmEditField.Value;
        end

        % Value changed function: X1stOrdernmEditField
        function X1stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.x_drift(2) = app.X1stOrdernmEditField.Value;
        end

        % Value changed function: X2ndOrdernmEditField
        function X2ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.x_drift(3) = app.X2ndOrdernmEditField.Value;
        end

        % Value changed function: XNoisefractionalEditField
        function XNoisefractionalEditFieldValueChanged(app, event)
            app.Driftpar.x_drift(4) = app.XNoisefractionalEditField.Value;
        end

        % Value changed function: YConstantnmEditField
        function YConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.y_drift(1) = app.YConstantnmEditField.Value;
        end

        % Value changed function: Y1stOrdernmEditField
        function Y1stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.y_drift(2) = app.Y1stOrdernmEditField.Value;
        end

        % Value changed function: Y2ndOrdernmEditField
        function Y2ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.y_drift(3) = app.Y2ndOrdernmEditField.Value;
        end

        % Value changed function: YNoisefractionalEditField
        function YNoisefractionalEditFieldValueChanged(app, event)
            app.Driftpar.y_drift(4) = app.YNoisefractionalEditField.Value;
        end

        % Value changed function: XRotationCenterEditField
        function XRotationCenterEditFieldValueChanged(app, event)
            value = app.XRotationCenterEditField.Value;
            if isempty(app.ImageSize.n)
                warndlg('Define image pattern first !','Warning');
            elseif value<=app.ImageSize.n
                app.Driftpar.rot_drift(1) = app.XRotationCenterEditField.Value;
            else
                warndlg('Center of rotation must be within image !','Warning');
            end
        end

        % Value changed function: YRotationCenterEditField
        function YRotationCenterEditFieldValueChanged(app, event)
            value = app.YRotationCenterEditField.Value;
            if value<=app.ImageSize.m
                app.Driftpar.rot_drift(2) = app.YRotationCenterEditField.Value;
            else
                warndlg('Center of rotation must be within image !','Warning');
            end
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            app.Driftpar.rot_drift(3) = app.RotationEditField.Value;
        end

        % Value changed function: RotationNoiseEditField
        function RotationNoiseEditFieldValueChanged(app, event)
            app.Driftpar.rot_drift(4)  = app.RotationNoiseEditField.Value;
        end

        % Value changed function: ndOrdernmEditField
        function ndOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.z_drift(3) = app.ndOrdernmEditField.Value;
        end

        % Value changed function: ConstantnmEditField
        function ConstantnmEditFieldValueChanged(app, event)
            app.Driftpar.z_drift(1) = app.ConstantnmEditField.Value;
        end

        % Value changed function: stOrdernmEditField
        function stOrdernmEditFieldValueChanged(app, event)
            app.Driftpar.z_drift(2) = app.stOrdernmEditField.Value;
        end

        % Value changed function: NoisefractionalEditField
        function NoisefractionalEditFieldValueChanged(app, event)
            app.Driftpar.z_drift(4) = app.NoisefractionalEditField.Value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            Reenable(app.CallingApp)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Reenable(app.CallingApp)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConstantnmEditFieldLabel = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel.Text = 'Constant [nm]';
            app.XConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XConstantnmEditField.ValueDisplayFormat = '%11.3g';
            app.XConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @XConstantnmEditFieldValueChanged, true);
            app.DriftSetupLabel = uilabel(app.UIFigure);
            app.DriftSetupLabel.Text = 'Drift Setup';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.stOrdernmEditFieldLabel = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel.Text = '1st Order [nm]';
            app.X1stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X1stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @X1stOrdernmEditFieldValueChanged, true);
            app.stOrdernmEditField_2Label = uilabel(app.UIFigure);
            app.stOrdernmEditField_2Label.Text = '2st Order [nm]';
            app.X2ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X2ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @X2ndOrdernmEditFieldValueChanged, true);
            app.NoisefractionalEditFieldLabel = uilabel(app.UIFigure);
            app.NoisefractionalEditFieldLabel.Text = 'Noise [fractional]';
            app.XNoisefractionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.XNoisefractionalEditField.Limits = [0 1];
            app.XNoisefractionalEditField.ValueChangedFcn = createCallbackFcn(app, @XNoisefractionalEditFieldValueChanged, true);
            app.XTranslationalDriftLabel = uilabel(app.UIFigure);
            app.XTranslationalDriftLabel.Text = 'X Translational Drift';
            app.YTranslationalDriftLabel = uilabel(app.UIFigure);
            app.YTranslationalDriftLabel.Text = 'Y Translational Drift';
            app.ConstantnmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel_2.Text = 'Constant [nm]';
            app.YConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @YConstantnmEditFieldValueChanged, true);
            app.stOrdernmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel_2.Text = '1st Order [nm]';
            app.Y1stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @Y1stOrdernmEditFieldValueChanged, true);
            app.stOrdernmEditField_2Label_2 = uilabel(app.UIFigure);
            app.stOrdernmEditField_2Label_2.Text = '2st Order [nm]';
            app.Y2ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @Y2ndOrdernmEditFieldValueChanged, true);
            app.NoisefractionalEditFieldLabel_2 = uilabel(app.UIFigure);
            app.NoisefractionalEditFieldLabel_2.Text = 'Noise [fractional]';
            app.YNoisefractionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.YNoisefractionalEditField.Limits = [0 1];
            app.YNoisefractionalEditField.ValueChangedFcn = createCallbackFcn(app, @YNoisefractionalEditFieldValueChanged, true);
            app.RotationalDriftLabel = uilabel(app.UIFigure);
            app.RotationalDriftLabel.Text = ' Rotational Drift';
            app.XCenterofRotationRasterLabel = uilabel(app.UIFigure);
            app.XCenterofRotationRasterLabel.Text = 'X Center of Rotation [Raster]';
            app.XRotationCenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRotationCenterEditField.Limits = [0 Inf];
            app.XRotationCenterEditField.ValueChangedFcn = createCallbackFcn(app, @XRotationCenterEditFieldValueChanged, true);
            app.YCenterofRotationRasterLabel = uilabel(app.UIFigure);
            app.YCenterofRotationRasterLabel.Text = 'Y Center of Rotation [Raster]';
            app.YRotationCenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.YRotationCenterEditField.Limits = [0 Inf];
            app.YRotationCenterEditField.ValueChangedFcn = createCallbackFcn(app, @YRotationCenterEditFieldValueChanged, true);
            app.RotationperframeLabel = uilabel(app.UIFigure);
            app.RotationperframeLabel.Text = 'Rotation per frame [°]';
            app.RotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationEditField.Limits = [-360 360];
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            app.NoisefractionalEditFieldLabel_3 = uilabel(app.UIFigure);
            app.NoisefractionalEditFieldLabel_3.Text = 'Noise [fractional]';
            app.RotationNoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationNoiseEditField.Limits = [0 1];
            app.RotationNoiseEditField.ValueChangedFcn = createCallbackFcn(app, @RotationNoiseEditFieldValueChanged, true);
            app.ZTranslationalDriftLabel = uilabel(app.UIFigure);
            app.ZTranslationalDriftLabel.Text = 'Z Translational Drift';
            app.ndOrdernmEditFieldLabel = uilabel(app.UIFigure);
            app.ndOrdernmEditFieldLabel.Text = '2nd Order [nm]';
            app.ndOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ndOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @ndOrdernmEditFieldValueChanged, true);
            app.ConstantnmEditFieldLabel_3 = uilabel(app.UIFigure);
            app.ConstantnmEditFieldLabel_3.Text = 'Constant [nm]';
            app.ConstantnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConstantnmEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantnmEditFieldValueChanged, true);
            app.stOrdernmEditFieldLabel_3 = uilabel(app.UIFigure);
            app.stOrdernmEditFieldLabel_3.Text = '1st Order [nm]';
            app.stOrdernmEditField = uieditfield(app.UIFigure, 'numeric');
            app.stOrdernmEditField.ValueChangedFcn = createCallbackFcn(app, @stOrdernmEditFieldValueChanged, true);
            app.NoisefractionalEditFieldLabel_4 = uilabel(app.UIFigure);
            app.NoisefractionalEditFieldLabel_4.Text = 'Noise [fractional]';
            app.NoisefractionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisefractionalEditField.Limits = [0 1];
            app.NoisefractionalEditField.ValueChangedFcn = createCallbackFcn(app, @NoisefractionalEditFieldValueChanged, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drift(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end